classdef UDP_client_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        sw_pos_d              matlab.ui.control.Switch
        ed_pos_d              matlab.ui.control.TextArea
        sl_kv                 matlab.ui.control.Slider
        lbl_kv                matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        LampLabel             matlab.ui.control.Label
        CopylastrecordButton  matlab.ui.control.Button
        PlotlastrecordButton  matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        KRButton              matlab.ui.control.Button
        KLButton              matlab.ui.control.Button
        HRButton              matlab.ui.control.Button
        HLButton              matlab.ui.control.Button
        Image3                matlab.ui.control.Image
        LOG                   matlab.ui.control.TextArea
        RerobAppExoTAOLabel   matlab.ui.control.Label
        ax_force              matlab.ui.control.UIAxes
        ax_vel                matlab.ui.control.UIAxes
        ax_pos                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        UI % Description
        VARS
        COM
    end
    
    methods (Access = private)
        
        function updateLine(~,line,time,value)

            if(time == -1)
                line.XData = nan;
                line.YData = nan;
                return
            end
        
            if isnan(line.XData)
                line.XData = time;
                line.YData = value;
        
            elseif (-line.XData(1)+line.XData(end)) <= 5
                line.XData = [line.XData time];
                line.YData = [line.YData value];
                if length(line.XData) >= 10
                    set(line.Parent,'XLim',[line.XData(1) line.XData(1)+5])
                end
        
            else
                line.XData = [line.XData(2:end) time];
                line.YData = [line.YData(2:end) value];
                set(line.Parent,'XLim',[line.XData(1) line.XData(end)])
            end       
        
        end

        function onDataUDP(app,~,~)

            SHM_VARS_STREAM_KEY = hex2dec('1010');
        
            s_read = app.COM.S.read(1);
            
            key = typecast(uint8(s_read.Data(1:2)),'int16');    
           
            persistent t_last count_k
        
            if isempty(count_k)
                count_k = 0;
            end
        
            count_k = count_k + 1;

            if(count_k == 10)
                app.Lamp.Color = [0.47,0.67,0.19];
                count_k = 0;
            end

             if key == SHM_VARS_STREAM_KEY
                VARS_STREAM = shm_vars_stream_cast(s_read.Data);
                app.VARS.VARS_STREAM = VARS_STREAM;

                if isempty(t_last)
                    t_last = VARS_STREAM.t_s;
                else
                    if(t_last == VARS_STREAM.t_s)
                        return;
                    end
        
                    if t_last > VARS_STREAM.t_s
                        VARS_STREAM.t_s = -1;
                    end
                    
                    t_last = VARS_STREAM.t_s;
                end
                
                app.updateLine(app.UI.("KR").l_pos_l,VARS_STREAM.t_s,VARS_STREAM.exo_knee_rigth_pos_out);
                app.updateLine(app.UI.("KR").l_pos_m,VARS_STREAM.t_s,VARS_STREAM.exo_knee_rigth_pos_in/100);
                app.updateLine(app.UI.("KR").l_vel_l,VARS_STREAM.t_s,VARS_STREAM.exo_knee_rigth_vel_out);
                app.updateLine(app.UI.("KR").l_vel_m,VARS_STREAM.t_s,VARS_STREAM.exo_knee_rigth_vel_in);

                app.updateLine(app.UI.("KL").l_pos_l,VARS_STREAM.t_s,VARS_STREAM.exo_knee_left_pos_out);
                app.updateLine(app.UI.("KL").l_pos_m,VARS_STREAM.t_s,VARS_STREAM.exo_knee_left_pos_in/100);
                app.updateLine(app.UI.("KL").l_vel_l,VARS_STREAM.t_s,VARS_STREAM.exo_knee_left_vel_out);
                app.updateLine(app.UI.("KL").l_vel_m,VARS_STREAM.t_s,VARS_STREAM.exo_knee_left_vel_in);

                app.updateLine(app.UI.("HR").l_pos_l,VARS_STREAM.t_s,VARS_STREAM.exo_hip_rigth_pos_out);
                app.updateLine(app.UI.("HR").l_pos_m,VARS_STREAM.t_s,VARS_STREAM.exo_hip_rigth_pos_in/100);
                app.updateLine(app.UI.("HR").l_vel_l,VARS_STREAM.t_s,VARS_STREAM.exo_hip_rigth_vel_out);
                app.updateLine(app.UI.("HR").l_vel_m,VARS_STREAM.t_s,VARS_STREAM.exo_hip_rigth_vel_in);

                app.updateLine(app.UI.("HL").l_pos_l,VARS_STREAM.t_s,VARS_STREAM.exo_hip_left_pos_out);
                app.updateLine(app.UI.("HL").l_pos_m,VARS_STREAM.t_s,VARS_STREAM.exo_hip_left_pos_in/100);
                app.updateLine(app.UI.("HL").l_vel_l,VARS_STREAM.t_s,VARS_STREAM.exo_hip_left_vel_out);
                app.updateLine(app.UI.("HL").l_vel_m,VARS_STREAM.t_s,VARS_STREAM.exo_hip_left_vel_in);
                       
             end   
        end
        
        function sendAliveServer(app,~,~)
              write(app.COM.S,[ uint8('ACK') 10 ],"uint8",app.VARS.EXOTAO_IP,1515);
              app.Lamp.Color = [1.00,0.58,0.40];
              app.addLOG("Sending keep alive command")
        end
        
        function timer_sendPos_d(app)
            t = toc(app.VARS.pos_d_tic);
            eval([app.VARS.pos_d_fnc{:}]);
            write(app.COM.S, uint8(sprintf('pos_d: %.2f %.2f %.2f %.2f\n',HR_pos_d,HL_pos_d,KR_pos_d,KL_pos_d)) ,"uint8",app.VARS.EXOTAO_IP,1515);
        end
    end
    
    methods (Access = public)
        
        function addLOG(app,txt)
            app.LOG.Value = [app.LOG.Value ;  sprintf("[%s] %s",string(datetime),txt)];
            scroll(app.LOG, 'bottom');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            pause(1)
            app.UIFigure.Position(1:2) = [758   251];
            drawnow;            

            app.UI.btnsSelectFrame = [app.HRButton app.HLButton app.KRButton app.KLButton];

            axs = [app.ax_pos app.ax_vel app.ax_force];
            set(axs,'nextplot','add')
                

            app.VARS.Kv = [0,0,0,0];
            app.VARS.joints = ["HR" "HL" "KR" "KL"];
            app.VARS.cur_joint = 1;

            for idx = 1 : numel (app.VARS.joints)
                J = app.VARS.joints(idx);
                app.UI.(J).plots = copy(axs);
                app.UI.(J).ax_pos = app.UI.(J).plots(1);
                app.UI.(J).ax_vel = app.UI.(J).plots(2);
                app.UI.(J).ax_force = app.UI.(J).plots(3);

                app.UI.(J).ax_pos.Title.String = strcat(app.UI.(J).ax_pos.Title.String," ",J);
                app.UI.(J).ax_vel.Title.String = strcat(app.UI.(J).ax_vel.Title.String," ",J);
                app.UI.(J).ax_force.Title.String = strcat(app.UI.(J).ax_force.Title.String," ",J);

                
                app.UI.(J).l_pos_m = plot(nan,nan,'Parent',app.UI.(J).ax_pos);
                app.UI.(J).l_pos_l = plot(nan,nan,'Parent',app.UI.(J).ax_pos);
                app.UI.(J).l_vel_m = plot(nan,nan,'Parent',app.UI.(J).ax_vel);
                app.UI.(J).l_vel_l = plot(nan,nan,'Parent',app.UI.(J).ax_vel);
    
                app.UI.(J).lines = [app.UI.(J).l_pos_m,app.UI.(J).l_pos_l,app.UI.(J).l_vel_m,app.UI.(J).l_vel_l];
                    
                set(app.UI.(J).plots,'parent',app.UIFigure);
                set(app.UI.(J).plots,'visible','off');
                set(app.UI.(J).lines,'visible','off');
            end

            delete(axs)    

            app.UI.JointControlsAdd = [app.sl_kv, app.lbl_kv];

            set(app.UI.JointControlsAdd,"visible","off");
            
            if ~isempty(timerfindall)
                stop(timerfindall)
            end

            [~,TempIP] = system("source ~/.config/rerobapp/rerobapp.conf >/dev/null ; echo -n $EXOTAO_IP_ADDR");

            app.VARS.EXOTAO_IP = TempIP;

            fprintf(1,"IP: %s\n",app.VARS.EXOTAO_IP)

            app.COM.S = udpport('datagram');
            
            t = timer("StartDelay",1,"TimerFcn",@(~,~)app.sendAliveServer,"ExecutionMode","fixedRate","Period",2);
            start(t)

            configureCallback(app.COM.S,"datagram",1,@app.onDataUDP)

            app.VARS.pos_d_timer = timer("StartDelay",0,"TimerFcn",@(~,~)app.timer_sendPos_d,"ExecutionMode","fixedRate","Period",.05);
            
            assignin("base","app",app)

        end

        % Button pushed function: HLButton, HRButton, KLButton, KRButton
        function HLButtonPushed(app, event)
            set(app.UI.JointControlsAdd,"visible","on");
            Jcur = event.Source.Text;

            for idx = 1 : numel (app.VARS.joints)
                J = app.VARS.joints(idx);
                if(isequal(J,Jcur))
                    set(app.UI.(J).plots,'visible','on');
                    set(app.UI.(J).lines,'visible','on');
                    app.VARS.cur_joint = idx;
                else
                    set(app.UI.(J).plots,'visible','off');
                    set(app.UI.(J).lines,'visible','off');
                end
            end

            for btn = app.UI.btnsSelectFrame
                if(isequal(btn.Text,Jcur))
                    set(btn,'backgroundcolor',[0.47,0.67,0.19]);
                else
                    set(btn,'backgroundcolor',[0.96,0.96,0.96]);
                end
            end

            drawnow
        end

        % Button pushed function: CopylastrecordButton
        function CopylastrecordButtonPushed(app, event)
            [~,cmdout] = system("source ~/.config/rerobapp/rerobapp.conf ; scp -r $EXOTAO_USER_SSH:/home/debian/out .");
            app.addLOG(cmdout);
        end

        % Button pushed function: PlotlastrecordButton
        function PlotlastrecordButtonPushed(app, event)
            
            fileName = "file_Knee_R.dat";
            switch app.VARS.cur_joint
                case 1 
                    fileName="file_Hip_R.dat";
                case 2 
                    fileName="file_Hip_L.dat";
                case 3 
                    fileName="file_Knee_R.dat";
                case 4 
                    fileName="file_Knee_L.dat";
            end

            plotLastData(500+app.VARS.cur_joint,strcat("out/",fileName))
           
        end

        % Value changed function: sl_kv
        function sl_kvValueChanged(app, event)
            value = app.sl_kv.Value;
            write(app.COM.S, uint8(sprintf('KV:%.2f\n',value)) ,"uint8",app.VARS.EXOTAO_IP,1515);
        end

        % Value changed function: sw_pos_d
        function sw_pos_dValueChanged(app, event)
            value = app.sw_pos_d.Value;
            if strcmp(value,'On')
                app.ed_pos_d.Enable = 'off';
                app.VARS.pos_d_fnc = app.ed_pos_d.Value;
                app.VARS.pos_d_tic = tic();
                start(app.VARS.pos_d_timer);
            else
                app.ed_pos_d.Enable = 'on';
                stop(app.VARS.pos_d_timer);
                for idx = 1:4
                    write(app.COM.S, uint8(sprintf('pos_d: %.2f %.2f %.2f %.2f\n',0,0,0,0)) ,"uint8",app.VARS.EXOTAO_IP,1515);
                    pause(0.1)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax_pos = uiaxes(app.UIFigure);
            title(app.ax_pos, 'Position')
            ylabel(app.ax_pos, 'Angle [ rad ]')
            zlabel(app.ax_pos, 'Z')
            app.ax_pos.YLim = [-2 2];
            app.ax_vel = uiaxes(app.UIFigure);
            title(app.ax_vel, 'Velocity')
            ylabel(app.ax_vel, 'Velocity [ rad/s ]')
            zlabel(app.ax_vel, 'Z')
            app.ax_vel.YLim = [-5000 5000];
            app.ax_force = uiaxes(app.UIFigure);
            title(app.ax_force, 'Force')
            xlabel(app.ax_force, 'Time [ s ]')
            ylabel(app.ax_force, 'Force [ N ]')
            zlabel(app.ax_force, 'Z')
            app.ax_force.YLim = [-6 6];
            app.ax_force.YTick = [-6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6];
            app.RerobAppExoTAOLabel = uilabel(app.UIFigure);
            app.RerobAppExoTAOLabel.Text = 'RerobApp (ExoTAO)';
            app.LOG = uitextarea(app.UIFigure);
            app.LOG.Editable = 'off';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'img', 'exo1.png');
            app.HLButton = uibutton(app.UIFigure, 'push');
            app.HLButton.ButtonPushedFcn = createCallbackFcn(app, @HLButtonPushed, true);
            app.HLButton.Text = 'HL';
            app.HRButton = uibutton(app.UIFigure, 'push');
            app.HRButton.ButtonPushedFcn = createCallbackFcn(app, @HLButtonPushed, true);
            app.HRButton.Text = 'HR';
            app.KLButton = uibutton(app.UIFigure, 'push');
            app.KLButton.ButtonPushedFcn = createCallbackFcn(app, @HLButtonPushed, true);
            app.KLButton.Text = 'KL';
            app.KRButton = uibutton(app.UIFigure, 'push');
            app.KRButton.ButtonPushedFcn = createCallbackFcn(app, @HLButtonPushed, true);
            app.KRButton.Text = 'KR';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'img', 'eesc.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'iberdiscap.jpg');
            app.PlotlastrecordButton = uibutton(app.UIFigure, 'push');
            app.PlotlastrecordButton.ButtonPushedFcn = createCallbackFcn(app, @PlotlastrecordButtonPushed, true);
            app.PlotlastrecordButton.Text = 'Plot last record';
            app.CopylastrecordButton = uibutton(app.UIFigure, 'push');
            app.CopylastrecordButton.ButtonPushedFcn = createCallbackFcn(app, @CopylastrecordButtonPushed, true);
            app.CopylastrecordButton.Text = 'Copy last record';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = ' ';
            app.Lamp = uilamp(app.UIFigure);
            app.lbl_kv = uilabel(app.UIFigure);
            app.lbl_kv.Text = 'Kv';
            app.sl_kv = uislider(app.UIFigure);
            app.sl_kv.Limits = [0 40];
            app.sl_kv.ValueChangedFcn = createCallbackFcn(app, @sl_kvValueChanged, true);
            app.ed_pos_d = uitextarea(app.UIFigure);
            app.ed_pos_d.Value = {'f = 0.2;'; 'HR_pos_d = sin(2*pi*f*t);'; 'HL_pos_d = 0.5*sin(2*pi*f*t);'; 'KR_pos_d = 0.5*(cos(2*pi*f*t)-1);'; 'KL_pos_d = 1*(cos(2*pi*f*t)-1);'};
            app.sw_pos_d = uiswitch(app.UIFigure, 'slider');
            app.sw_pos_d.ValueChangedFcn = createCallbackFcn(app, @sw_pos_dValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UDP_client_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end